classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        Tab                matlab.ui.container.Tab
        Label_4            matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label              matlab.ui.control.Label
        Image5             matlab.ui.control.Image
        Image4             matlab.ui.control.Image
        Image3             matlab.ui.control.Image
        Image2             matlab.ui.control.Image
        ButtonGroup        matlab.ui.container.ButtonGroup
        Button_5           matlab.ui.control.ToggleButton
        Button_4           matlab.ui.control.ToggleButton
        Button_3           matlab.ui.control.ToggleButton
        Button_2           matlab.ui.control.ToggleButton
        Tab_3              matlab.ui.container.Tab
        Image7_2           matlab.ui.control.Image
        TabGroup2          matlab.ui.container.TabGroup
        Tab_4              matlab.ui.container.Tab
        cEditField         matlab.ui.control.EditField
        cEditFieldLabel    matlab.ui.control.Label
        gxyclog1fxyLabel   matlab.ui.control.Label
        Tab_5              matlab.ui.container.Tab
        EditField_4        matlab.ui.control.EditField
        EditField_4Label   matlab.ui.control.Label
        cEditField_2       matlab.ui.control.NumericEditField
        cEditField_2Label  matlab.ui.control.Label
        gxycfxyLabel       matlab.ui.control.Label
        DropDown_3         matlab.ui.control.DropDown
        Label_25           matlab.ui.control.Label
        Slider_3           matlab.ui.control.Slider
        Label_14           matlab.ui.control.Label
        Slider_2           matlab.ui.control.Slider
        Label_13           matlab.ui.control.Label
        Label_11           matlab.ui.control.Label
        Image6_2           matlab.ui.control.Image
        Tab_2              matlab.ui.container.Tab
        Knob               matlab.ui.control.Knob
        Label_27           matlab.ui.control.Label
        Image7             matlab.ui.control.Image
        Label_8            matlab.ui.control.Label
        Label_7            matlab.ui.control.Label
        Image6             matlab.ui.control.Image
        Slider             matlab.ui.control.Slider
        Label_5            matlab.ui.control.Label
        Tab4               matlab.ui.container.Tab
        EditField_2        matlab.ui.control.NumericEditField
        Label_18           matlab.ui.control.Label
        Label_20           matlab.ui.control.Label
        Label_19           matlab.ui.control.Label
        Image9             matlab.ui.control.Image
        Image8             matlab.ui.control.Image
        Image6_3           matlab.ui.control.Image
        Tab5               matlab.ui.container.Tab
        DropDown_4         matlab.ui.control.DropDown
        Label_26           matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        Label_21           matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        Button_6           matlab.ui.control.Button
        Image11            matlab.ui.control.Image
        Image10            matlab.ui.control.Image
        EditField_3        matlab.ui.control.EditField
        DropDown_2         matlab.ui.control.DropDown
        Label_24           matlab.ui.control.Label
        Image              matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function ImageClicked(app, event)
            % 打开本地文件选择对话框
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                                      '选择一张图片');
            % 检查用户是否选择了文件
            if isequal(file, 0)
                return; % 如果没有选择文件，则退出回调函数
            end
    
            % 构建完整文件路径
            filePath = fullfile(path, file);
    
            % 在 Image 控件中显示选择的图片
            app.Image.ImageSource = filePath;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
    % 检查当前按钮并执行相应操作
    if selectedButton == app.Button_2
        % 灰度化
        if isempty(app.Image.ImageSource)
            uialert(app.UIFigure, '请先加载图片！', '错误');
            return;
        end

        % 读取图片并转换为灰度图
        img = imread(app.Image.ImageSource);
        grayImg = rgb2gray(img);

        % 保存并显示结果
        outputFile = 'gray_result.png';
        imwrite(grayImg, outputFile);
        app.Image2.ImageSource = outputFile;

    elseif selectedButton == app.Button_3
        % 直方图显示
        if isempty(app.Image.ImageSource)
            uialert(app.UIFigure, '请先加载图片！', '错误');
            return;
        end

        % 读取图片并绘制直方图
        img = imread(app.Image.ImageSource);
        grayImg = rgb2gray(img);
        histFile = 'histogram_result.png';
        figure; imhist(grayImg); % 显示直方图
        saveas(gcf, histFile); % 保存为图片
        close; % 关闭临时图像窗口
        app.Image3.ImageSource = histFile;

    elseif selectedButton == app.Button_4
        % 直方图均衡化
        if isempty(app.Image.ImageSource)
            uialert(app.UIFigure, '请先加载图片！', '错误');
            return;
        end

        % 读取图片并均衡化
        img = imread(app.Image.ImageSource);
        grayImg = rgb2gray(img);
        equalizedImg = histeq(grayImg);

        % 保存均衡化图像
        equalizedFile = 'equalized_result.png';
        imwrite(equalizedImg, equalizedFile);
        app.Image4.ImageSource = equalizedFile;

        % 保存均衡化后的直方图
        histEqFile = 'equalized_histogram.png';
        figure; imhist(equalizedImg); % 绘制直方图
        saveas(gcf, histEqFile); % 保存图片
        app.Image4.ImageSource = histEqFile;

    elseif selectedButton == app.Button_5
        % 直方图匹配（规定化）
        if isempty(app.Image.ImageSource)
            uialert(app.UIFigure, '请先加载图片！', '错误');
            return;
        end

        % 读取图片并执行直方图匹配
        img = imread(app.Image.ImageSource);
        grayImg = rgb2gray(img);

       % 生成随机参考灰度图像
        referenceGray = uint8(255 * rand(size(grayImg))); % 大小与输入图像相同

        % 匹配直方图
        matchedImg = imhistmatch(grayImg, referenceGray);

        % 保存匹配后图像
        matchedFile = 'matched_result.png';
        imwrite(matchedImg, matchedFile);
        %app.Image5.ImageSource = matchedFile;

        % 保存匹配后的直方图
        matchedHistFile = 'matched_histogram.png';
        figure; imhist(matchedImg); % 绘制直方图
        saveas(gcf, matchedHistFile); % 保存图片
        app.Image5.ImageSource = matchedHistFile;

    else
        % 未知操作
        uialert(app.UIFigure, '未知操作', '错误');
    end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            
            % 读取图片并进行缩放
            img = imread(app.Image.ImageSource);
            scaleValue = value / 100; % 获取滑块的百分比值
            [origHeight, origWidth, numChannels] = size(img); % 原图尺寸
            
            scaledImg = imresize(img, scaleValue); % 缩放图像
            
            % 创建黑色背景，尺寸与原始图像相同
            if numChannels == 3
                background = zeros(origHeight, origWidth, 3, 'uint8'); % RGB 图像
            else
                background = zeros(origHeight, origWidth, 'uint8'); % 灰度图像
            end
        
            % 将缩放后的图像嵌入背景中央
            [scaledHeight, scaledWidth, ~] = size(scaledImg);
            top = floor((origHeight - scaledHeight) / 2) + 1;
            left = floor((origWidth - scaledWidth) / 2) + 1;
            background(top:top + scaledHeight - 1, left:left + scaledWidth - 1, :) = scaledImg;

            % 保存并更新 Image6 显示（使用唯一文件名强制刷新）
            scaledFile = ['scaled_image_', num2str(now, '%.10f'), '.png'];
            imwrite(background, scaledFile);
            app.Image6.ImageSource = scaledFile;
        end

        % Callback function
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
    
            % 读取图片并进行旋转
            img = imread(app.Image.ImageSource);
            angle = value; % 获取输入的角度值
            rotatedImg = imrotate(img, angle); % 旋转图像
            
            % 保存并更新 Image7 显示（使用唯一文件名强制刷新）
            rotatedFile = ['rotated_image_', num2str(now, '%.10f'), '.png'];
            imwrite(rotatedImg, rotatedFile);
            app.Image7.ImageSource = rotatedFile;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            brightnessValue = app.Slider_2.Value; % 获取亮度值
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            % 加载图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转为 double 类型方便计算
            % 应用亮度调整
            imgLinear = img + brightnessValue / 100;
            imgLinear = imgLinear * (1 + app.Slider_3.Value / 100); % 当前对比度值
            imgLinear = min(max(imgLinear, 0), 1); % 限制在 [0,1] 范围
            % 更新线性变换结果显示
            linearFile = ['linear_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgLinear, linearFile);
            app.Image6_2.ImageSource = linearFile;
        end

        % Value changed function: Slider_3
        function Slider_3ValueChanged(app, event)
            contrastValue = app.Slider_3.Value; % 获取对比度值
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
            % 加载图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转为 double 类型方便计算
            % 应用对比度调整
            imgLinear = img + app.Slider_2.Value / 100; % 当前亮度值
            imgLinear = imgLinear * (1 + contrastValue / 100);
            imgLinear = min(max(imgLinear, 0), 1); % 限制在 [0,1] 范围
            % 更新线性变换结果显示
            linearFile = ['linear_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgLinear, linearFile);
            app.Image6_2.ImageSource = linearFile;
        end

        % Callback function
        function ListBoxValueChanged(app, event)
            nonlinearTransform = app.ListBox.Value; % 获取非线性变换选项
            
            % 加载图像
            img = imread('gray_result.png');
            img = im2double(img); % 转为 double 类型方便计算
            % 应用非线性变换
            if strcmp(nonlinearTransform, '对数变换')
                imgNonlinear = log(1 + img); % 对数变换
            elseif strcmp(nonlinearTransform, '指数变换')
                imgNonlinear = exp(img) - 1; % 指数变换
            else
                imgNonlinear = img; % 默认不变
            end
            % 正规化到 [0,1] 范围
            imgNonlinear = imgNonlinear / max(imgNonlinear(:));
            % 更新非线性变换结果显示
            nonlinearFile = ['nonlinear_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgNonlinear, nonlinearFile);
            app.Image7_2.ImageSource = nonlinearFile;
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            % 获取当前选择的值
            nonlinearTransform = app.DropDown_3.Value;
        
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
        
            % 加载图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转为 double 类型方便计算
        
            % 应用非线性变换
            if strcmp(nonlinearTransform, '对数变换')
                imgNonlinear = log(1 + img); % 对数变换
            elseif strcmp(nonlinearTransform, '指数变换')
                imgNonlinear = exp(img) - 1; % 指数变换
            else
                imgNonlinear = img; % 默认不变
            end
        
            % 正规化到 [0,1] 范围
            imgNonlinear = imgNonlinear / max(imgNonlinear(:));
        
            % 更新非线性变换结果显示
            nonlinearFile = ['nonlinear_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgNonlinear, nonlinearFile);
            app.Image7_2.ImageSource = nonlinearFile;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)

            % 获取输入值，用于控制噪声类型
            noiseType = app.EditField_2.Value;
        
            % 检查是否已加载图像
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
        
            % 加载原始图像
            img = imread(app.Image.ImageSource);
            img = im2double(img); % 转换为 double 类型方便处理
        
            % 加噪处理
            if noiseType == 1
                % 高斯噪声
                noisyImg = imnoise(img, 'gaussian', 0, 0.01);
            elseif noiseType == 2
                % 盐椒噪声
                noisyImg = imnoise(img, 'salt & pepper', 0.02);
            elseif noiseType == 3
                % 均匀噪声
                noisyImg = img + 0.1 * (rand(size(img)) - 0.5);
                noisyImg = min(max(noisyImg, 0), 1); % 限制范围到 [0,1]
            else
                uialert(app.UIFigure, '请输入有效的噪声类型值（1: 高斯, 2: 盐椒, 3: 均匀）！', '错误');
                return;
            end
        
            % 更新加噪图像显示
            noisyFile = ['noisy_image_', num2str(now, '%.10f'), '.png'];
            imwrite(noisyImg, noisyFile);
            app.Image6_3.ImageSource = noisyFile;
        
            % 空域滤波（中值滤波）
            filteredImgSpatial = medfilt2(rgb2gray(noisyImg), [3 3]); % 灰度处理后中值滤波
            spatialFile = ['spatial_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImgSpatial, spatialFile);
            app.Image8.ImageSource = spatialFile;
        
            % 频域滤波（理想低通滤波）
            [rows, cols] = size(rgb2gray(noisyImg)); % 获取图像大小
            D0 = 30; % 截止频率
            [U, V] = meshgrid(1:cols, 1:rows);
            D = sqrt((U - cols / 2).^2 + (V - rows / 2).^2);
            H = double(D <= D0); % 低通滤波器
            
            % 频域处理
            fftImg = fftshift(fft2(rgb2gray(noisyImg)));
            filteredImgFreq = real(ifft2(ifftshift(fftImg .* H)));
            filteredImgFreq = mat2gray(filteredImgFreq); % 归一化
            
            freqFile = ['frequency_filtered_image_', num2str(now, '%.10f'), '.png'];
            imwrite(filteredImgFreq, freqFile);
            app.Image9.ImageSource = freqFile;
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
    
            % 读取原图像
            img = imread(app.Image.ImageSource); % 从app.Image.ImageSource获取原图像路径
            img_gray = rgb2gray(img); % 将图像转为灰度图像
        
            % 根据选择的算子进行边缘检测
            switch value
                case '罗伯特算子'
                    % 使用Robert算子进行边缘检测
                    edge_img = edge(img_gray, 'Roberts');
                case 'Prewitt算子'
                    % 使用Prewitt算子进行边缘检测
                    edge_img = edge(img_gray, 'Prewitt');
                case 'Sobel算子'
                    % 使用Sobel算子进行边缘检测
                    edge_img = edge(img_gray, 'Sobel');
                case '拉普拉斯算子'
                    % 使用拉普拉斯算子进行边缘检测
                    edge_img = edge(img_gray, 'log'); % 'log'表示拉普拉斯算子
                otherwise
                    % 默认行为
                    edge_img = img_gray;
            end
            
            % 保存边缘检测图像并更新Image10
            edgeFile = ['edge_image_', num2str(now, '%.10f'), '.png'];
            imwrite(edge_img, edgeFile); % 保存边缘检测图像
            app.Image10.ImageSource = edgeFile; % 更新Image10控件的ImageSource为边缘图像
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % 读取原图像
            img = imread(app.Image.ImageSource);  % 获取原图像
            
            % 转为灰度图像
            img_gray = rgb2gray(img);
            
            % 目标提取（基于阈值的简单分割示例，您可以根据需要替换为更复杂的算法）
            threshold = 0.5;  % 设置阈值
            binary_img = imbinarize(img_gray, threshold);  % 二值化图像进行目标提取
            
            % 进行形态学操作去除噪声（可选）
            se = strel('disk', 5);  % 结构元素
            binary_img = imopen(binary_img, se);  % 开运算去噪
            
            % 保存目标提取图像并更新Image11
            targetFile = ['target_image_', num2str(now, '%.10f'), '.png'];
            imwrite(binary_img, targetFile);  % 保存目标提取图像
            app.Image11.ImageSource = targetFile;  % 更新Image11控件的ImageSource为目标提取结果
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
           value = app.DropDown_4.Value;  % 获取下拉框选项

            % 读取原图像和目标提取图像
            img = imread(app.Image.ImageSource);  % 获取原图像
            img_gray = rgb2gray(img);  % 将图像转为灰度图像
            target_img = imread(app.Image11.ImageSource);  % 获取目标提取后的图像
            
            % 特征提取结果初始化
            originalFeatures = [];
            targetFeatures = [];
            
            % 根据选择的特征提取方法进行特征提取
            switch value
                case 'LBP'
                    % LBP特征提取
                    originalFeatures = extractLBPFeatures(img_gray);
                    targetFeatures = extractLBPFeatures(target_img);
                case 'HOG'
                    % HOG特征提取
                    [originalFeatures, ~] = extractHOGFeatures(img_gray);
                    [targetFeatures, ~] = extractHOGFeatures(target_img);
            end
        
            % 更新TextArea显示特征提取结果
            app.TextArea.Value = sprintf("Original Features: %s\nTarget Features: %s", ...
                mat2str(originalFeatures, 5), mat2str(targetFeatures, 5));
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            disp(['Text Area updated: ', value]);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            if isempty(app.Image.ImageSource)
                uialert(app.UIFigure, '请先加载图片！', '错误');
                return;
            end
    
            % 读取图片并进行旋转
            img = imread(app.Image.ImageSource);
            angle = value; % 获取输入的角度值
            rotatedImg = imrotate(img, angle); % 旋转图像
            
            % 保存并更新 Image7 显示（使用唯一文件名强制刷新）
            rotatedFile = ['rotated_image_', num2str(now, '%.10f'), '.png'];
            imwrite(rotatedImg, rotatedFile);
            app.Image7.ImageSource = rotatedFile;
        end

        % Value changed function: cEditField
        function cEditFieldValueChanged(app, event)
            value = app.cEditField.Value;
            % 加载图像
            img = imread('gray_result.png');
            img = im2double(img); % 转为 double 类型方便计算
            imgNonlinear = value * log(1 + img); % 对数变换
            % 正规化到 [0,1] 范围
            imgNonlinear = imgNonlinear / max(imgNonlinear(:));
            % 更新非线性变换结果显示
            nonlinearFile = ['nonlinear_image_', num2str(now, '%.10f'), '.png'];
            imwrite(imgNonlinear, nonlinearFile);
            app.Image7_2.ImageSource = nonlinearFile;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Label_24 = uilabel(app.UIFigure);
            app.Label_24.Text = '图像分类';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_2.Items = {'', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown_2.Value = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '图像预处理';
            app.ButtonGroup = uibuttongroup(app.Tab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Text = '灰度化';
            app.Button_2.Value = true;
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Text = '直方图显示';
            app.Button_4 = uitogglebutton(app.ButtonGroup);
            app.Button_4.Text = '直方图均衡化';
            app.Button_5 = uitogglebutton(app.ButtonGroup);
            app.Button_5.Text = '直方图匹配';
            app.Image2 = uiimage(app.Tab);
            app.Image3 = uiimage(app.Tab);
            app.Image4 = uiimage(app.Tab);
            app.Image5 = uiimage(app.Tab);
            app.Label = uilabel(app.Tab);
            app.Label.Text = '灰度图像';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '直方图';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '直方图均衡化';
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '直方图匹配';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '对比度增强';
            app.Image6_2 = uiimage(app.Tab_3);
            app.Label_11 = uilabel(app.Tab_3);
            app.Label_11.Text = '线性变换';
            app.Label_13 = uilabel(app.Tab_3);
            app.Label_13.Text = '亮度';
            app.Slider_2 = uislider(app.Tab_3);
            app.Slider_2.Limits = [-100 100];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Label_14 = uilabel(app.Tab_3);
            app.Label_14.Text = '对比度';
            app.Slider_3 = uislider(app.Tab_3);
            app.Slider_3.Limits = [-100 100];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @Slider_3ValueChanged, true);
            app.Label_25 = uilabel(app.Tab_3);
            app.Label_25.Text = '非线性变换';
            app.DropDown_3 = uidropdown(app.Tab_3);
            app.DropDown_3.Items = {'对数变换', '指数变换'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '对数变换';
            app.TabGroup2 = uitabgroup(app.Tab_3);
            app.Tab_4 = uitab(app.TabGroup2);
            app.Tab_4.Title = '对数变换';
            app.gxyclog1fxyLabel = uilabel(app.Tab_4);
            app.gxyclog1fxyLabel.Text = 'g(x,y)=c⋅log(1+f(x,y))';
            app.cEditFieldLabel = uilabel(app.Tab_4);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.Tab_4, 'text');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cEditFieldValueChanged, true);
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = '指数变换';
            app.gxycfxyLabel = uilabel(app.Tab_5);
            app.gxycfxyLabel.Text = 'g(x,y)=c⋅(f(x,y)) γ';
            app.cEditField_2Label = uilabel(app.Tab_5);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.Tab_5, 'numeric');
            app.EditField_4Label = uilabel(app.Tab_5);
            app.EditField_4Label.Text = 'γ';
            app.EditField_4 = uieditfield(app.Tab_5, 'text');
            app.Image7_2 = uiimage(app.Tab_3);
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '图像变换';
            app.Label_5 = uilabel(app.Tab_2);
            app.Label_5.Text = '缩放比例(%)';
            app.Slider = uislider(app.Tab_2);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Image6 = uiimage(app.Tab_2);
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '缩放变换';
            app.Label_8 = uilabel(app.Tab_2);
            app.Label_8.Text = '旋转变换';
            app.Image7 = uiimage(app.Tab_2);
            app.Label_27 = uilabel(app.Tab_2);
            app.Label_27.Text = '旋转角度';
            app.Knob = uiknob(app.Tab_2, 'continuous');
            app.Knob.Limits = [-180 180];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.Image6_3 = uiimage(app.Tab4);
            app.Image8 = uiimage(app.Tab4);
            app.Image9 = uiimage(app.Tab4);
            app.Label_19 = uilabel(app.Tab4);
            app.Label_19.Text = '空域滤波';
            app.Label_20 = uilabel(app.Tab4);
            app.Label_20.Text = '频域滤波';
            app.Label_18 = uilabel(app.Tab4);
            app.Label_18.Text = {'图像加噪            '; '1: 高斯, 2: 盐椒, 3: 均匀'};
            app.EditField_2 = uieditfield(app.Tab4, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.Image10 = uiimage(app.Tab5);
            app.Image11 = uiimage(app.Tab5);
            app.Button_6 = uibutton(app.Tab5, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '目标提取';
            app.TextArea = uitextarea(app.Tab5);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Label_21 = uilabel(app.Tab5);
            app.Label_21.Text = '边缘检测';
            app.DropDown = uidropdown(app.Tab5);
            app.DropDown.Items = {'罗伯特算子', 'Prewitt算子', 'Sobel算子', '拉普拉斯算子'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '罗伯特算子';
            app.Label_26 = uilabel(app.Tab5);
            app.Label_26.Text = '特征提取';
            app.DropDown_4 = uidropdown(app.Tab5);
            app.DropDown_4.Items = {'LBP', 'HOG'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = 'LBP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end